classdef project_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        pfaEditFieldLabel    matlab.ui.control.Label
        pfaEditField         matlab.ui.control.NumericEditField
        pfaEditField_2Label  matlab.ui.control.Label
        pfaEditField_2       matlab.ui.control.NumericEditField
        snrEditFieldLabel    matlab.ui.control.Label
        snrEditField         matlab.ui.control.NumericEditField
        Button               matlab.ui.control.Button
        Button2              matlab.ui.control.Button
        pdEditFieldLabel     matlab.ui.control.Label
        pdEditField          matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            dbstop in uicomponents\+matlab\+ui\+control\+internal\+model\AbstractNumericComponent.m at 87
            
            pfaa=app.pfaEditField_2.Value;
            snr=app.snrEditField.Value;
                       
            app.pdEditField.Value = num2str(pfdans(pfaa,snr));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            pfa=app.pfaEditField.Value;
            
            
             [snr, pd] = pd1graph(pfa);
             
            plot(app.UIAxes,snr,pd);
            
            axis(app.UIAxes,[0 18 0 100]);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Of Detection v/s Signal to Noise Ratio')
            xlabel(app.UIAxes, 'Signal to Noise Ratio')
            ylabel(app.UIAxes, 'Probability Of Detection')
            app.pfaEditFieldLabel = uilabel(app.UIFigure);
            app.pfaEditFieldLabel.Text = 'pfa';
            app.pfaEditField = uieditfield(app.UIFigure, 'numeric');
            app.pfaEditField_2Label = uilabel(app.UIFigure);
            app.pfaEditField_2Label.Text = 'pfa';
            app.pfaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.snrEditFieldLabel = uilabel(app.UIFigure);
            app.snrEditFieldLabel.Text = 'snr';
            app.snrEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.pdEditFieldLabel = uilabel(app.UIFigure);
            app.pdEditFieldLabel.Text = 'pd';
            app.pdEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = project_app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end